classdef ECE107L_E01_06_Buenaventura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BUENAVENTURABAIRDROUANS2014107362Label  matlab.ui.control.Label
        MAY22020Label      matlab.ui.control.Label
        ECE107LE0106Label  matlab.ui.control.Label
        EDITButtonGroup    matlab.ui.container.ButtonGroup
        OUTPUT1Button      matlab.ui.control.RadioButton
        OUTPUT2Button      matlab.ui.control.RadioButton
        GetImageButton     matlab.ui.control.Button
        SaveImageButton    matlab.ui.control.Button
        GreyScaleButton    matlab.ui.control.Button
        FILTERSLabel       matlab.ui.control.Label
        Output2            matlab.ui.control.UIAxes
        SOURCELabel        matlab.ui.control.Label
        Input              matlab.ui.control.UIAxes
        OUTPUT2Label       matlab.ui.control.Label
        OUTPUT1Label       matlab.ui.control.Label
        Output1            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
           run FilePath.m
           fullpath = [filepath, filename];
           ImageFile = imread(fullpath);
           imshow(ImageFile, 'parent',app.Output1)
           imshow(ImageFile, 'parent',app.Output2)
           imshow(ImageFile, 'parent',app.Input)
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if(app.OUTPUT1Button.Value == 1)
                F = getframe(app.Output1);
                Image = frame2im(F);
                imwrite(Image, 'Output1.png')
            end
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)
            if(app.OUTPUT1Button.Value == 1)
                  x = rgb2gray(app.Output1.ImageSource);
                  imwrite(x,'Output1.png','png')
                  path = fileparts(which('ECE107L_E01_06_Buenaventura'));
                  fullpath = [path, '\Output1.png'];
                  ImageFile = imread(fullpath);
                  app.Output1.ImageSource = ImageFile;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BUENAVENTURABAIRDROUANS2014107362Label = uilabel(app.UIFigure);
            app.BUENAVENTURABAIRDROUANS2014107362Label.Text = {'BUENAVENTURA, BAIRD ROUAN S.'; '2014107362'};
            app.MAY22020Label = uilabel(app.UIFigure);
            app.MAY22020Label.Text = 'MAY 2, 2020';
            app.ECE107LE0106Label = uilabel(app.UIFigure);
            app.ECE107LE0106Label.Text = 'ECE107L-E01-06';
            app.EDITButtonGroup = uibuttongroup(app.UIFigure);
            app.EDITButtonGroup.Title = 'EDIT';
            app.OUTPUT1Button = uiradiobutton(app.EDITButtonGroup);
            app.OUTPUT1Button.Text = {'OUTPUT 1'; ''};
            app.OUTPUT1Button.Value = true;
            app.OUTPUT2Button = uiradiobutton(app.EDITButtonGroup);
            app.OUTPUT2Button.Text = 'OUTPUT 2';
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.GreyScaleButton = uibutton(app.UIFigure, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            app.FILTERSLabel = uilabel(app.UIFigure);
            app.FILTERSLabel.Text = 'FILTERS';
            app.Output1 = uiaxes(app.UIFigure);
            title(app.Output1, '')
            xlabel(app.Output1, '')
            ylabel(app.Output1, '')
            app.Output1.XTick = [];
            app.Output1.YTick = [];
            app.Output1.ZTick = 0;
            app.OUTPUT1Label = uilabel(app.UIFigure);
            app.OUTPUT1Label.Text = 'OUTPUT 1';
            app.Output2 = uiaxes(app.UIFigure);
            title(app.Output2, '')
            xlabel(app.Output2, '')
            ylabel(app.Output2, '')
            app.Output2.XTick = [];
            app.Output2.YTick = [];
            app.Output2.ZTick = 0;
            app.OUTPUT2Label = uilabel(app.UIFigure);
            app.OUTPUT2Label.Text = 'OUTPUT 2';
            app.Input = uiaxes(app.UIFigure);
            title(app.Input, '')
            xlabel(app.Input, '')
            ylabel(app.Input, '')
            app.Input.XTick = [];
            app.Input.YTick = [];
            app.Input.ZTick = 0;
            app.SOURCELabel = uilabel(app.UIFigure);
            app.SOURCELabel.Text = 'SOURCE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE107L_E01_06_Buenaventura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end