classdef ImageProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageProcessingBairdRouanSBuenaventuraUIFigure  matlab.ui.Figure
        OUTPUT1Label                   matlab.ui.control.Label
        OUTPUT2Label                   matlab.ui.control.Label
        ORIGINALLabel                  matlab.ui.control.Label
        OpenImageButton                matlab.ui.control.Button
        UIAxesOriginal                 matlab.ui.control.UIAxes
        UIAxesOutput1                  matlab.ui.control.UIAxes
        UIAxesOutput2                  matlab.ui.control.UIAxes
        ImageOutput1CheckBox           matlab.ui.control.CheckBox
        ImageOutput2CheckBox           matlab.ui.control.CheckBox
        Lamp1                          matlab.ui.control.Lamp
        Lamp2                          matlab.ui.control.Lamp
        RESETButton1                   matlab.ui.control.Button
        RESETButton2                   matlab.ui.control.Button
        ApplyIndexButton               matlab.ui.control.Button
        SaveButton1                    matlab.ui.control.Button
        SaveButton2                    matlab.ui.control.Button
        ImageFilterPanel1RGB           matlab.ui.container.Panel
        RedSliderLabel                 matlab.ui.control.Label
        RedSlider                      matlab.ui.control.Slider
        BlueSliderLabel                matlab.ui.control.Label
        BlueSlider                     matlab.ui.control.Slider
        GreenSliderLabel               matlab.ui.control.Label
        GreenSlider                    matlab.ui.control.Slider
        SaturationLabel                matlab.ui.control.Label
        SaturationSlider               matlab.ui.control.Slider
        HueSliderLabel                 matlab.ui.control.Label
        HueSlider                      matlab.ui.control.Slider
        UseAsOriginalButton1           matlab.ui.control.Button
        UseAsOriginalButton2           matlab.ui.control.Button
        SY19203QECE107LE01Label        matlab.ui.control.Label
        ImageFilterPanel3ImageFiltering  matlab.ui.container.Panel
        SmoothenSliderLabel            matlab.ui.control.Label
        SmoothenSlider                 matlab.ui.control.Slider
        SharpenSliderLabel             matlab.ui.control.Label
        SharpenSlider                  matlab.ui.control.Slider
        MotionBlurSliderLabel          matlab.ui.control.Label
        MotionBlurSlider               matlab.ui.control.Slider
        MotionBlurDegreesSpinnerLabel  matlab.ui.control.Label
        MotionBlurDegreesSpinner       matlab.ui.control.Spinner
        ImageFilterPanel2GrayscaledImages  matlab.ui.container.Panel
        IndexedImageSliderLabel        matlab.ui.control.Label
        IndexedImageSlider             matlab.ui.control.Slider
        AdjustContrastButton           matlab.ui.control.Button
        ConvertToGrayscaleImageButton  matlab.ui.control.Button
        ConvertToBinaryImageButton     matlab.ui.control.Button
    end

    methods (Access = private)
        function RGBSlider(app)
             temp = imread('temp.jpg','jpg'); %Read temp image
             temp(:,:,1) = temp(:,:,1) + app.RedSlider.Value; %Get value of pixels on red and add sliderred value
             temp(:,:,2) = temp(:,:,2) + app.GreenSlider.Value; %Get value of pixels on red and add sliderred value
             temp(:,:,3) = temp(:,:,3) + app.BlueSlider.Value; %Get value of pixels on red and add sliderred value
             saveTemp(app,temp); %Run SaveTemp function
        end
        function motionBlur(app)
            blur = fspecial('motion',app.MotionBlurSlider.Value,app.MotionBlurDegreesSpinner.Value);
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = imfilter(temp,blur); %Apply special filter using imfilter
            saveTemp(app,temp); %Run SaveTemp function
        end
        function saveTemp(app,temp)
            value1 = app.ImageOutput1CheckBox.Value; %Gets value if checkbox1 is enabled
            value2 = app.ImageOutput2CheckBox.Value; %Gets value if checkbox2 is enabled
            if(value1 == true) %Check if checkbox1 is enabled
                imshow(temp,'parent',app.UIAxesOutput1); %Display the image to the Output1
                imwrite(temp,'temp1.jpg','jpg'); %Save temp1 image
            end
            if(value2 == true) %Check if checkbox2 is enabled
                imshow(temp,'parent',app.UIAxesOutput2); %Show image to Output2
                imwrite(temp,'temp2.jpg','jpg'); %Save temp2 image
            end
        end
        function UseAsOriginal(app,temp)
            imwrite(temp,'temp.jpg','jpg'); %Create temporary image temp
            imwrite(temp,'temp1.jpg','jpg'); %Create temporary image temp1
            imwrite(temp,'temp2.jpg','jpg'); %Create temporary image temp2
            imshow(temp,'parent',app.UIAxesOriginal); %Display the image to the Original Axes
            imshow(temp,'parent',app.UIAxesOutput1); %Display the image to the Output1 Axes
            imshow(temp,'parent',app.UIAxesOutput2); %Display the image to the Output2 Axes
        end
        function deleteTemp(app)
            delete('temp.jpg'); %Delete temp image
            delete('temp1.jpg'); %Delete temp image
            delete('temp2.jpg'); %Delete temp image
            delete(app); %Close application
        end
        function enableOutput1(app)
            app.ImageOutput1CheckBox.Value = true; %Enable Checkbox1
            app.Lamp1.Color = [0;1;0]; %Turn lamp1 color to green
            app.SaveButton1.Enable = true; %Enable SaveButton1
            app.UseAsOriginalButton1.Enable = true; %Enable Use as Original Button1
        end
        function disableOutput1(app)
            app.ImageOutput1CheckBox.Value = false; %Disable Checkbox1
            app.Lamp1.Color = [1;0;0]; %Turn lamp1 color to red
            app.SaveButton1.Enable = false; %Disable SaveButton1
            app.UseAsOriginalButton1.Enable = false; %Disable Use as Original Button1
        end
        function enableOutput2(app)
            app.ImageOutput2CheckBox.Value = true; %Disable Checkbox1
            app.Lamp2.Color = [0;1;0]; %Turn lamp2 color to green
            app.SaveButton2.Enable = true; %Enable SaveButton2
            app.UseAsOriginalButton2.Enable = true; %Enable Use as Original Button2
        end
        function disableOutput2(app)
            app.ImageOutput2CheckBox.Value = false; %Disable Checkbox2
            app.Lamp2.Color = [1;0;0]; %Turn lamp2 color to red
            app.SaveButton2.Enable = false; %Disable SaveButton2
            app.UseAsOriginalButton2.Enable = false; %Disable Use as Original Button2
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            global imageOriginal; %declare variable
            [filename, pathname] = uigetfile({'*.jpg;*.png'},"Open File"); %Allows user to select an image
            if(ischar(filename)) %Checks if file is a valid file
                fullpathname = strcat(pathname,filename); %Get full file path
                imageOriginal = imread(fullpathname); %Reads and stores image to the variable
                imwrite(imageOriginal,'temp.jpg','jpg'); %Create temporary image temp
                imwrite(imageOriginal,'temp1.jpg','jpg'); %Create temporary image temp1
                imwrite(imageOriginal,'temp2.jpg','jpg'); %Create temporary image temp2
                imshow(imageOriginal,'parent',app.UIAxesOriginal); %Display the image to the Original Axes
                imshow(imageOriginal,'parent',app.UIAxesOutput1); %Display the image to the Output1 Axes
                imshow(imageOriginal,'parent',app.UIAxesOutput2); %Display the image to the Output2 Axes
                app.RESETButton1.Enable = true; %Enables reset button1
                app.RESETButton2.Enable = true; %Enables reset button2
                value1 = app.ImageOutput1CheckBox.Value; %Gets value if checkbox1 is enabled
                value2 = app.ImageOutput2CheckBox.Value; %Gets value if checkbox2 is enabled
                if(value1 == true) %Check if checkbox 1 is enabled
                    enableOutput1(app); %Run enable Output1 function
                end
                if(value1 == false) %Check if checkbox 1 is disabled
                    disableOutput1(app); %Run disable Output1 function
                end
                if(value2 == true) %Check if checkbox 2 is enabled
                    enableOutput2(app); %Run enable Output2 function
                end
                if(value2 == false) %Check if checkbox 2 is disabled
                    disableOutput2(app); %Run disable Output2 function
                end
            end
        end

        % Close request function: 
        % ImageProcessingBairdRouanSBuenaventuraUIFigure
        function ImageProcessingBairdRouanSBuenaventuraUIFigureCloseRequest(app, event)
            deleteTemp(app); %Tun deleteTemp functio
        end

        % Value changed function: ImageOutput1CheckBox
        function ImageOutput1CheckBoxValueChanged(app, event)
            value = app.ImageOutput1CheckBox.Value; %Get value from checkbox1
            if(value == false) %Check if checkbox1 is disabled
                disableOutput1(app); %Run disableOutput1 function
                enableOutput2(app); %Run enableOutput2 function
            end
            if(value == true) %Check if checkbox1 is enabled
                enableOutput1(app); %Run enableOutput1 function
            end
        end

        % Value changed function: ImageOutput2CheckBox
        function ImageOutput2CheckBoxValueChanged(app, event)
            value = app.ImageOutput2CheckBox.Value; %Get value from checkbox2
            if(value == false) %Check if checkbox2 is disabled
                disableOutput2(app); %Run disableOutput2 function
                enableOutput1(app); %Run enableOutput1 function
            end
            if(value == true) %Check if checkbox2 is enabled
                enableOutput2(app); %Run enableOutput2 function
            end
        end

        % Button pushed function: RESETButton1
        function RESETButton1Pushed(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Button pushed function: RESETButton2
        function RESETButton2Pushed(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Button pushed function: UseAsOriginalButton1
        function UseAsOriginalButton1Pushed(app, event)
            temp = imread('temp1.jpg','jpg'); %Read temp1 image
            UseAsOriginal(app,temp);
        end

        % Button pushed function: UseAsOriginalButton2
        function UseAsOriginalButton2Pushed(app, event)
            temp = imread('temp2.jpg','jpg'); %Read temp1 image
            UseAsOriginal(app,temp);
        end

        % Button pushed function: SaveButton1
        function SaveButton1Pushed(app, event)
            temp = imread('temp1.jpg','jpg'); %Read temp1 image
            imwrite(temp,'Output1.jpg','jpg'); %Save output1 image
        end

        % Button pushed function: SaveButton2
        function SaveButton2Pushed(app, event)
            temp = imread('temp2.jpg','jpg'); %Read temp1 image
            imwrite(temp,'Output2.jpg','jpg'); %Save output1 image
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            RGBSlider(app); %Run RGBSlider function
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            RGBSlider(app); %Run RGBSlider function
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            RGBSlider(app); %Run RGBSlider function
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = rgb2hsv(temp); %Turns RGB image to Hue, Saturation and Value image
            temp(:,:,2) = temp(:,:,2) + app.SaturationSlider.Value; %Get slider value and add saturations
            temp = hsv2rgb(temp); %Convert hsv image to rgb
            saveTemp(app,temp);%Run saveTemp function
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = rgb2hsv(temp); %Turns RGB image to Hue, Saturation and Value image
            temp(:,:,1) = temp(:,:,1) + app.HueSlider.Value; %Get slider value and add hue
            temp = hsv2rgb(temp); %Convert hsv image to rgb
            saveTemp(app,temp); %Run saveTemp function
        end

        % Value changed function: IndexedImageSlider
        function IndexedImageSliderValueChanged(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            intValue = round(app.IndexedImageSlider.Value); % Gets and rounds the slider value
            temp = rgb2ind(temp,intValue); %Turns RGB image to Indexed image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Button pushed function: ConvertToBinaryImageButton
        function ConvertToBinaryImageButtonPushed(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = rgb2gray(temp); %Turns RGB image to grayscale image
            temp = imbinarize(temp); %Turns grayscale image to binary image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Button pushed function: ConvertToGrayscaleImageButton
        function ConvertToGrayscaleImageButtonPushed(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = rgb2gray(temp); %Turns RGB image to grayscale image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Button pushed function: AdjustContrastButton
        function AdjustContrastButtonPushed(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = rgb2gray(temp); %Turns RGB image to grayscale image
            temp = imadjust(temp); %Adjust contrast of image based on highest and lowest intensity
            saveTemp(app,temp); %Run saveTemp function
        end

        % Value changed function: SharpenSlider
        function SharpenSliderValueChanged(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = imsharpen(temp,'Amount',app.SharpenSlider.Value) %Gets slider value and add sharpen to image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Value changed function: SmoothenSlider
        function SmoothenSliderValueChanged(app, event)
            temp = imread('temp.jpg','jpg'); %Read temp image
            temp = imgaussfilt(temp,app.SmoothenSlider.Value) %Gets slider value and smoothen image
            saveTemp(app,temp); %Run saveTemp function
        end

        % Value changed function: MotionBlurSlider
        function MotionBlurSliderValueChanged(app, event)
            motionBlur(app); %Run motionBluer function
        end

        % Value changed function: MotionBlurDegreesSpinner
        function MotionBlurDegreesSpinnerValueChanged(app, event)
            motionBlur(app); %Run motionBluer function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageProcessingBairdRouanSBuenaventuraUIFigure = uifigure('Visible', 'off');
            app.ImageProcessingBairdRouanSBuenaventuraUIFigure.Name = 'Image Processing - Baird Rouan S. Buenaventura';
            app.ImageProcessingBairdRouanSBuenaventuraUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageProcessingBairdRouanSBuenaventuraUIFigureCloseRequest, true);
            app.OUTPUT1Label = uilabel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.OUTPUT1Label.Text = 'OUTPUT 1';
            app.OUTPUT2Label = uilabel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.OUTPUT2Label.Text = 'OUTPUT 2';
            app.ORIGINALLabel = uilabel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ORIGINALLabel.Text = 'ORIGINAL';
            app.OpenImageButton = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.UIAxesOriginal = uiaxes(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            title(app.UIAxesOriginal, '')
            xlabel(app.UIAxesOriginal, '')
            ylabel(app.UIAxesOriginal, '')
            app.UIAxesOutput1 = uiaxes(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            title(app.UIAxesOutput1, '')
            xlabel(app.UIAxesOutput1, '')
            ylabel(app.UIAxesOutput1, '')
            app.UIAxesOutput2 = uiaxes(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            title(app.UIAxesOutput2, '')
            xlabel(app.UIAxesOutput2, '')
            ylabel(app.UIAxesOutput2, '')
            app.ImageOutput1CheckBox = uicheckbox(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ImageOutput1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageOutput1CheckBoxValueChanged, true);
            app.ImageOutput1CheckBox.Text = 'Image Output to Output 1';
            app.ImageOutput1CheckBox.Value = true;
            app.ImageOutput2CheckBox = uicheckbox(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ImageOutput2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageOutput2CheckBoxValueChanged, true);
            app.ImageOutput2CheckBox.Text = 'Image Output to Output 2';
            app.Lamp1 = uilamp(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.Lamp2 = uilamp(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.RESETButton1 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.RESETButton1.ButtonPushedFcn = createCallbackFcn(app, @RESETButton1Pushed, true);
            app.RESETButton1.Enable = 'off';
            app.RESETButton1.Text = 'RESET';
            app.RESETButton2 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.RESETButton2.ButtonPushedFcn = createCallbackFcn(app, @RESETButton2Pushed, true);
            app.RESETButton2.Enable = 'off';
            app.RESETButton2.Text = 'RESET';
            app.ApplyIndexButton = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.ApplyIndexButton.Text = 'Apply Index';
            app.SaveButton1 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.SaveButton1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton1Pushed, true);
            app.SaveButton1.Enable = 'off';
            app.SaveButton1.Text = 'SAVE';
            app.SaveButton2 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.SaveButton2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton2Pushed, true);
            app.SaveButton2.Enable = 'off';
            app.SaveButton2.Text = 'SAVE';
            app.ImageFilterPanel1RGB = uipanel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ImageFilterPanel1RGB.Title = 'Image Filter Panel 1 - RGB';
            app.RedSliderLabel = uilabel(app.ImageFilterPanel1RGB);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.ImageFilterPanel1RGB);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.ImageFilterPanel1RGB);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.ImageFilterPanel1RGB);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.ImageFilterPanel1RGB);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.ImageFilterPanel1RGB);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.SaturationLabel = uilabel(app.ImageFilterPanel1RGB);
            app.SaturationLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.ImageFilterPanel1RGB);
            app.SaturationSlider.Limits = [0 1];
            app.SaturationSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.HueSliderLabel = uilabel(app.ImageFilterPanel1RGB);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.ImageFilterPanel1RGB);
            app.HueSlider.Limits = [0 1];
            app.HueSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.UseAsOriginalButton1 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.UseAsOriginalButton1.ButtonPushedFcn = createCallbackFcn(app, @UseAsOriginalButton1Pushed, true);
            app.UseAsOriginalButton1.Enable = 'off';
            app.UseAsOriginalButton1.Text = 'Use As Original';
            app.UseAsOriginalButton2 = uibutton(app.ImageProcessingBairdRouanSBuenaventuraUIFigure, 'push');
            app.UseAsOriginalButton2.ButtonPushedFcn = createCallbackFcn(app, @UseAsOriginalButton2Pushed, true);
            app.UseAsOriginalButton2.Enable = 'off';
            app.UseAsOriginalButton2.Text = 'Use As Original';
            app.SY19203QECE107LE01Label = uilabel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.SY19203QECE107LE01Label.Text = 'SY1920 3Q ECE107L-E01     Buenaventura, Baird Rouan S. - 2014107362     Image Processing and Filtering';
            app.ImageFilterPanel3ImageFiltering = uipanel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ImageFilterPanel3ImageFiltering.Title = 'Image Filter Panel  3 - Image Filtering';
            app.SmoothenSliderLabel = uilabel(app.ImageFilterPanel3ImageFiltering);
            app.SmoothenSliderLabel.Text = 'Smoothen';
            app.SmoothenSlider = uislider(app.ImageFilterPanel3ImageFiltering);
            app.SmoothenSlider.Limits = [0 10];
            app.SmoothenSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothenSliderValueChanged, true);
            app.SharpenSliderLabel = uilabel(app.ImageFilterPanel3ImageFiltering);
            app.SharpenSliderLabel.Text = 'Sharpen';
            app.SharpenSlider = uislider(app.ImageFilterPanel3ImageFiltering);
            app.SharpenSlider.Limits = [0 2];
            app.SharpenSlider.ValueChangedFcn = createCallbackFcn(app, @SharpenSliderValueChanged, true);
            app.MotionBlurSliderLabel = uilabel(app.ImageFilterPanel3ImageFiltering);
            app.MotionBlurSliderLabel.Text = 'Motion Blur';
            app.MotionBlurSlider = uislider(app.ImageFilterPanel3ImageFiltering);
            app.MotionBlurSlider.ValueChangedFcn = createCallbackFcn(app, @MotionBlurSliderValueChanged, true);
            app.MotionBlurDegreesSpinnerLabel = uilabel(app.ImageFilterPanel3ImageFiltering);
            app.MotionBlurDegreesSpinnerLabel.Text = {'Motion Blur'; 'Degrees'};
            app.MotionBlurDegreesSpinner = uispinner(app.ImageFilterPanel3ImageFiltering);
            app.MotionBlurDegreesSpinner.Step = 5;
            app.MotionBlurDegreesSpinner.Limits = [0 180];
            app.MotionBlurDegreesSpinner.ValueChangedFcn = createCallbackFcn(app, @MotionBlurDegreesSpinnerValueChanged, true);
            app.ImageFilterPanel2GrayscaledImages = uipanel(app.ImageProcessingBairdRouanSBuenaventuraUIFigure);
            app.ImageFilterPanel2GrayscaledImages.Title = 'Image Filter Panel  2 - Grayscaled Images';
            app.IndexedImageSliderLabel = uilabel(app.ImageFilterPanel2GrayscaledImages);
            app.IndexedImageSliderLabel.Text = 'Indexed Image';
            app.IndexedImageSlider = uislider(app.ImageFilterPanel2GrayscaledImages);
            app.IndexedImageSlider.Limits = [0 255];
            app.IndexedImageSlider.ValueChangedFcn = createCallbackFcn(app, @IndexedImageSliderValueChanged, true);
            app.AdjustContrastButton = uibutton(app.ImageFilterPanel2GrayscaledImages, 'push');
            app.AdjustContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustContrastButtonPushed, true);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.ConvertToGrayscaleImageButton = uibutton(app.ImageFilterPanel2GrayscaledImages, 'push');
            app.ConvertToGrayscaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToGrayscaleImageButtonPushed, true);
            app.ConvertToGrayscaleImageButton.Text = 'Convert To Grayscale Image';
            app.ConvertToBinaryImageButton = uibutton(app.ImageFilterPanel2GrayscaledImages, 'push');
            app.ConvertToBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToBinaryImageButtonPushed, true);
            app.ConvertToBinaryImageButton.Text = 'Convert To Binary Image';
            % Show the figure after all components are created
            app.ImageProcessingBairdRouanSBuenaventuraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageProcessingBairdRouanSBuenaventuraUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageProcessingBairdRouanSBuenaventuraUIFigure)
        end
    end
end